classdef HydroponicsMainApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        SensorAlarmLamp        matlab.ui.control.Lamp
        SensorAlarmLampLabel   matlab.ui.control.Label
        SumpLamp               matlab.ui.control.Lamp
        SumpLampLabel          matlab.ui.control.Label
        CheckSensorButton      matlab.ui.control.Button
        UITable                matlab.ui.control.Table
        programoutputTextArea  matlab.ui.control.TextArea
        programoutputLabel     matlab.ui.control.Label
        sensorDropDown         matlab.ui.control.DropDown
        sensorLabel            matlab.ui.control.Label
        sensorvalueEditField   matlab.ui.control.NumericEditField
        sensorvalueLabel       matlab.ui.control.Label
        image                  matlab.ui.control.Image
        StartTestButton        matlab.ui.control.Button
    end

    
properties (Access = private)
     a1         % sensors Arduino   (TestProto: a1)
        a2         % motors Arduino    (TestProto: a2)
        servo2     % pump servo        (TestProto: servo2 on a2 D8)
        servo3     % pH servo          (TestProto: servo3 on a2 D4)
        s2         % stepper addon     (TestProto: s2 on a1)
        cam        % webcam            (TestProto: cam)
        threshold  % moisture / water-level threshold (TestProto: 2.0)
end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
   % Clear old handles if they exist
            clear app.a1;
            clear app.a2;
            clear app.servo2;
            clear app.servo3;
            clear app.s2;
            clear app.cam;
            

            % Lamps start green
            app.SumpLamp.Color        = [0 1 0];
            app.SensorAlarmLamp.Color = [0 1 0];

            % ====== MATCH TESTPROTO ASSIGNMENTS (ADJUST PORTS IF NEEDED) ======
            % Sensors Arduino  (TestProto: a1 = arduino('COM5',...))
            app.a1 = arduino('COM5','Uno', ...
                'Libraries', {'Servo','AccelStepperAddon/AccelStepperAddon'});

            % Motors Arduino   (TestProto: a2 = arduino('COM4',...))
            app.a2 = arduino('COM4','Uno', ...
                'Libraries', {'Servo','AccelStepperAddon/AccelStepperAddon'});

            % Camera (TestProto: cam = webcam(2);)
            app.cam = webcam(2);

            % Pump servo = servo2 (a2, D8)
            app.servo2 = servo(app.a2, 'D8', ...
                'MinPulseDuration', 700e-6, ...
                'MaxPulseDuration', 2300e-6);

            % pH servo = servo3 (a2, D4)
            app.servo3 = servo(app.a2, 'D4', ...
                'MinPulseDuration', 700e-6, ...
                'MaxPulseDuration', 2300e-6);

            % Stepper addon s2 on a1 (reservoir)
            app.s2 = addon(app.a1, 'AccelStepperAddon/AccelStepperAddon', ...
                           {'D8','D12','D11','D13'});
            app.s2.setMaxSpeed(400);
            app.s2.setAcceleration(400);

            % Threshold like TestProto
            app.threshold = 2.0;

            % UI table columns
            app.UITable.ColumnName = {'Year','Month','Day','Hour','Minute', ...
                'Conductivity','Water Temp','pH','Light','Height','Blooming','Voltage'};

            % Start button idle color
            app.StartTestButton.BackgroundColor = [0.30, 0.75, 0.93];

            % Physical LEDs (on a2) OFF at start
            writeDigitalPin(app.a2, 'D11', 0);  % sump LED OFF
            writeDigitalPin(app.a2, 'D12', 0);  % general alarm LED OFF
  

        end

        % Button pushed function: StartTestButton
        function StartTestButtonPushed(app, event)
    Char1 = {};

            % Visually show it's running
            app.StartTestButton.BackgroundColor = [0.85, 0.33, 0.10];

            % --- Load any existing table BEFORE run (same file as TestProto) ---
            filename = 'myHydroponicData.xlsx';
            if isfile(filename)
                ExistingData = readmatrix(filename);   % Excel must be CLOSED
            else
                ExistingData = [];
            end
            app.UITable.Data = ExistingData;

            % 1. Read moisture sensor / water level on A1 (TestProto: WaterLevel4App)
            [Voltage, Char1] = WaterLevel4App(app.a2, 'A1', Char1);
            Char1{end+1,1} = sprintf('Initial moisture / water-level (A1): %.2f V', Voltage);
            app.programoutputTextArea.Value = Char1;

            % 2. Decide pump state based on voltage + threshold (TestProto PumpOnOff4App)
            [PumpState, Char1] = PumpOnOff4App(app.servo2, Voltage, app.threshold, Char1);

            if PumpState
                Char1{end+1,1} = 'Pump is ON (moisture low).';
            else
                Char1{end+1,1} = 'Pump is OFF (moisture OK).';
            end
            app.programoutputTextArea.Value = Char1;
            pause(3);

            % 3. Check water flow (TestProto: writeDigitalPin a2, re-read A1)
            Char1{end+1,1} = 'Checking water flowing...';
            app.programoutputTextArea.Value = Char1;

            try
                writeDigitalPin(app.a2, 'A1', 1);
            catch
            end

            [Voltage, Char1] = WaterLevel4App(app.a2, 'A1', Char1);
            Char1{end+1,1} = sprintf('Water-flow check voltage (A1): %.2f V', Voltage);
            app.programoutputTextArea.Value = Char1;

            try
                writeDigitalPin(app.a2, 'A1', 0);
            catch
            end

            % Short pump test like TestProto
            %PumpFlag = PumpOnOff4App(app.servo2, 0.5, app.threshold, Char1); %#ok<NASGU>

            % 4. Check water level in sump (TestProto: waterLevelReading4App on A0)
            Char1{end+1,1} = 'Checking water level in the sump (A0)...';
            app.programoutputTextArea.Value = Char1;

            [sumpVoltage, Height, Char1] = waterLevelReading4App(app.a1, 'A0', Char1);
            Char1{end+1,1} = sprintf('Sump level voltage (A0): %.2f V', sumpVoltage);
            app.programoutputTextArea.Value = Char1;

            % 5. Move stepper valve to add fresh water (TestProto: stepperValve4App)
            Char1{end+1,1} = 'Opening reservoir valve with stepper...';
            app.programoutputTextArea.Value = Char1;

            MovedDistance = stepperValve4App(app.s2, 16000, Height);
            Char1{end+1,1} = sprintf('Stepper moved distance = %g steps', MovedDistance);
            app.programoutputTextArea.Value = Char1;

            pause(5);   % time for water to flow / valve motion

            % 6. Check pH (servo motion) – TestProto: pHservo4App(servo3,...)
            Char1{end+1,1} = 'Dipping a pH test strip...';
            Char1 = pHservo4App(app.servo3, Char1);
            app.programoutputTextArea.Value = Char1;

            % 7. Conductivity – TestProto: ppm = Conductivity4App(a1,'D3');
            Char1{end+1,1} = 'Checking conductivity of solution...';
            app.programoutputTextArea.Value = Char1;

            ppm = Conductivity4App(app.a1, 'D3');   % same as TestProto
            Char1{end+1,1} = sprintf('Conductivity reading: %.3f', ppm);
            app.programoutputTextArea.Value = Char1;

            % TestProto: if ppm > 2, blink LED on D12
            if ppm > 2
                try
                    writeDigitalPin(app.a2, 'D12', 1);
                    Char1{end+1,1} = 'Conductivity HIGH: D12 LED ON.';
                    app.programoutputTextArea.Value = Char1;
                    pause(3);
                    writeDigitalPin(app.a2, 'D12', 0);
                catch
                end
            else
                Char1{end+1,1} = 'Conductivity within limits.';
                app.programoutputTextArea.Value = Char1;
            end

            % 8. Water temperature – TestProto: TempF = WaterTemp4App(a1,'');
            Char1{end+1,1} = 'Checking water temperature...';
            app.programoutputTextArea.Value = Char1;

            TempF = WaterTemp4App(app.a1, '');
            Char1{end+1,1} = sprintf('Water temperature: %.2f F', TempF);
            app.programoutputTextArea.Value = Char1;

            % 9. pH reading – TestProto: pH = ReadPH4App(cam)
            Char1{end+1,1} = 'Reading nutrient water pH from camera...';
            app.programoutputTextArea.Value = Char1;

            pH = ReadPH4App(app.cam);
            Char1{end+1,1} = sprintf('pH: %.2f', pH);
            app.programoutputTextArea.Value = Char1;

            % 10. Vision system – TestProto: Vison4App(cam)
            Char1{end+1,1} = 'Calling vision code...';
            app.programoutputTextArea.Value = Char1;

            [LightLevel, PlantHeight, Blooming, img] = Vision4App(app.cam);
            app.image.ImageSource = img;
            imwrite(img, 'plantImage.jpg');

            % 11. Store data (same structure as TestProto)
            t = datetime('now');
            [y, monthVal, d] = ymd(t);
            [h, mi, ~]       = hms(t);

            % Data2Add = [y,month,d,h,m,ppm,TempF,pH,LightLevel,PlantHeight,Blooming,Voltage];
            Data2Add = [y, monthVal, d, h, mi, ppm, TempF, pH, ...
                        LightLevel, PlantHeight, Blooming, sumpVoltage];

            lr    = size(ExistingData, 1);
            s1    = ['A', num2str(lr+1)];
            s2rng = ['M', num2str(lr+1)];
            range = strcat(s1, ':', s2rng);

            writematrix(Data2Add, 'myHydroponicData.xlsx', 'Sheet', 1, 'Range', range);
            ExistingData(end+1, :) = Data2Add;
            app.UITable.Data = ExistingData;

            % 12. Alarms + lamps (same logic as before)
            limits = struct( ...
                'sumpV_min', 1.20, ...  % LOW SUMP threshold
                'ppm_max',   1200, ...  % conductivity upper limit
                'tempF_min', 50,  ...   % water temp lower
                'tempF_max', 80,  ...   % water temp upper
                'pH_min',    5.5, ...
                'pH_max',    6.5, ...
                'light_min', 50,  ...   % light level lower bound
                'height_min',0    ...   % height lower bound
            );

            alarms = {};

            % Low Sump indicator
            isLowSump = (sumpVoltage < limits.sumpV_min);
            if isLowSump, alarms{end+1} = 'LOW SUMP'; end

            % Update Sump lamp
            if isLowSump
                app.SumpLamp.Color = [1 0 0];
            else
                app.SumpLamp.Color = [0 1 0];
            end

            % Physical sump LED D11 on a2
            try
                writeDigitalPin(app.a2, 'D11', isLowSump);
            catch
            end

            % Other sensor limits
            if ppm   > limits.ppm_max
                alarms{end+1} = 'High Electrical Conductivity';
            end
            if TempF < limits.tempF_min || TempF > limits.tempF_max
                alarms{end+1} = 'Water Temp Out of Range';
            end
            if pH    < limits.pH_min   || pH    > limits.pH_max
                alarms{end+1} = 'pH Out of Range';
            end
            if LightLevel < limits.light_min
                alarms{end+1} = 'Low Light';
            end
            if PlantHeight < limits.height_min
                alarms{end+1} = 'Height Low';
            end

            % General alarm (any alarm besides pure LOW SUMP)
            isOtherAlarm = false;
            if ~isempty(alarms)
                isOtherAlarm = any(~strcmp(alarms, 'LOW SUMP'));
            end

            if isOtherAlarm
                app.SensorAlarmLamp.Color = [1 0 0];
            else
                app.SensorAlarmLamp.Color = [0 1 0];
            end
            
          
            % Physical general LED D12 on a2
            try
                writeDigitalPin(app.a2, 'D12', isOtherAlarm);
            catch
            
            end


            % Log alarms
            if ~isempty(alarms)
                Char1{end+1,1} = sprintf('ALARM: %s', strjoin(alarms, ', '));
                app.programoutputTextArea.Value = Char1;
            end

            % Pump Check Cyle. Read moisture sensor / water level on A1 (TestProto: WaterLevel4App)
            
            [Voltage, Char1] = WaterLevel4App(app.a2, 'A1', Char1);
            Char1{end+1,1} = sprintf('Initial moisture / water-level (A1): %.2f V', Voltage);
            app.programoutputTextArea.Value = Char1;

            % Decide pump state based on voltage + threshold (TestProto PumpOnOff4App)
            [PumpState, Char1] = PumpOnOff4App(app.servo2, Voltage, app.threshold, Char1);

            if PumpState
                Char1{end+1,1} = 'Pump is ON (moisture low).';
            else
                Char1{end+1,1} = 'Pump is OFF (moisture OK).';
            end
            app.programoutputTextArea.Value = Char1;
            pause(15);

            [Voltage, Char1] = WaterLevel4App(app.a2, 'A1', Char1);
            Char1{end+1,1} = sprintf('Initial moisture / water-level (A1): %.2f V', Voltage);
            app.programoutputTextArea.Value = Char1;

            % Decide pump state based on voltage + threshold (TestProto PumpOnOff4App)
            [PumpState, Char1] = PumpOnOff4App(app.servo2, Voltage, app.threshold, Char1);

            if PumpState
                Char1{end+1,1} = 'Pump is ON (moisture low).';
            else
                Char1{end+1,1} = 'Pump is OFF (moisture OK).';
            end
            app.programoutputTextArea.Value = Char1;
            pause(15);

            [Voltage, Char1] = WaterLevel4App(app.a2, 'A1', Char1);
            Char1{end+1,1} = sprintf('Initial moisture / water-level (A1): %.2f V', Voltage);
            app.programoutputTextArea.Value = Char1;

            % Decide pump state based on voltage + threshold (TestProto PumpOnOff4App)
            [PumpState, Char1] = PumpOnOff4App(app.servo2, Voltage, app.threshold, Char1);

            if PumpState
                Char1{end+1,1} = 'Pump is ON (moisture low).';
            else
                Char1{end+1,1} = 'Pump is OFF (moisture OK).';
            end
            app.programoutputTextArea.Value = Char1;
            pause(15);

            [Voltage, Char1] = WaterLevel4App(app.a2, 'A1', Char1);
            Char1{end+1,1} = sprintf('Initial moisture / water-level (A1): %.2f V', Voltage);
            app.programoutputTextArea.Value = Char1;

            % Decide pump state based on voltage + threshold (TestProto PumpOnOff4App)
            [PumpState, Char1] = PumpOnOff4App(app.servo2, Voltage, app.threshold, Char1);

            if PumpState
                Char1{end+1,1} = 'Pump is ON (moisture low).';
            else
                Char1{end+1,1} = 'Pump is OFF (moisture OK).';
            end
            app.programoutputTextArea.Value = Char1;
            pause(15);

            [Voltage, Char1] = WaterLevel4App(app.a2, 'A1', Char1);
            Char1{end+1,1} = sprintf('Initial moisture / water-level (A1): %.2f V', Voltage);
            app.programoutputTextArea.Value = Char1;

            % Decide pump state based on voltage + threshold (TestProto PumpOnOff4App)
            [PumpState, Char1] = PumpOnOff4App(app.servo2, Voltage, app.threshold, Char1);

            if PumpState
                Char1{end+1,1} = 'Pump is ON (moisture low).';
            else
                Char1{end+1,1} = 'Pump is OFF (moisture OK).';
            end
            app.programoutputTextArea.Value = Char1;
            pause(15);

            writePosition(app.servo2, 0.5);

            % Done
            app.StartTestButton.BackgroundColor = [0.30, 0.75, 0.93];
            disp('Model 1 test complete.');


        end

        % Button pushed function: CheckSensorButton
        function CheckSensorButtonPushed(app, event)
        
ExistingData = readmatrix('myHydroponicData.xlsx');
            lr = size(ExistingData,1);

            switch app.sensorDropDown.Value
                case "Conductivity"         % col 6
                    val = ExistingData(lr,6);
                case "Water Temp"           % col 7
                    val = ExistingData(lr,7);
                case "pH"                   % col 8
                    val = ExistingData(lr,8);
                case "Light"                % col 9
                    val = ExistingData(lr,9);
                case "Height"               % col 10
                    val = ExistingData(lr,10);
                case "Blooming"             % col 11
                    val = ExistingData(lr,11);
                case "Water Level"          % col 12
                    val = ExistingData(lr,12);
            end

            app.sensorvalueEditField.Value = val;
        

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.StartTestButton = uibutton(app.UIFigure, 'push');
            app.StartTestButton.ButtonPushedFcn = createCallbackFcn(app, @StartTestButtonPushed, true);
            app.StartTestButton.Text = 'Start Test';
            app.image = uiimage(app.UIFigure);
            app.sensorvalueLabel = uilabel(app.UIFigure);
            app.sensorvalueLabel.Text = 'sensor value';
            app.sensorvalueEditField = uieditfield(app.UIFigure, 'numeric');
            app.sensorLabel = uilabel(app.UIFigure);
            app.sensorLabel.Text = 'sensor';
            app.sensorDropDown = uidropdown(app.UIFigure);
            app.sensorDropDown.Items = {'Conductivity', 'Water Temp', 'pH', 'Light', 'Height', 'Blooming', 'Water Level'};
            app.sensorDropDown.Value = 'Conductivity';
            app.programoutputLabel = uilabel(app.UIFigure);
            app.programoutputLabel.Text = 'program output';
            app.programoutputTextArea = uitextarea(app.UIFigure);
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.CheckSensorButton = uibutton(app.UIFigure, 'push');
            app.CheckSensorButton.ButtonPushedFcn = createCallbackFcn(app, @CheckSensorButtonPushed, true);
            app.CheckSensorButton.Text = 'Check Sensor';
            app.SumpLampLabel = uilabel(app.UIFigure);
            app.SumpLampLabel.Text = 'Sump';
            app.SumpLamp = uilamp(app.UIFigure);
            app.SensorAlarmLampLabel = uilabel(app.UIFigure);
            app.SensorAlarmLampLabel.Text = 'Sensor Alarm';
            app.SensorAlarmLamp = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HydroponicsMainApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end